classdef Challenges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChallengesUIFigure          matlab.ui.Figure
        ChangeGUI                   matlab.ui.control.Button
        ConsoleLabel                matlab.ui.control.Label
        Console                     matlab.ui.control.TextArea
        ConnectBT                   matlab.ui.control.StateButton
        ComportEditFieldLabel       matlab.ui.control.Label
        Comport                     matlab.ui.control.NumericEditField
        TabGroup                    matlab.ui.container.TabGroup
        WallrunTab                  matlab.ui.container.Tab
        SetupPanel                  matlab.ui.container.Panel
        GoButton                    matlab.ui.control.Button
        StartpowerEditFieldLabel    matlab.ui.control.Label
        Startpower                  matlab.ui.control.NumericEditField
        BreakpowerEditFieldLabel    matlab.ui.control.Label
        Breakpower                  matlab.ui.control.NumericEditField
        stopdistanceEditFieldLabel  matlab.ui.control.Label
        stopdistance                matlab.ui.control.NumericEditField
        RuntimeEditFieldLabel       matlab.ui.control.Label
        Runtime                     matlab.ui.control.NumericEditField
        Panel                       matlab.ui.container.Panel
        Save_data                   matlab.ui.control.Button
        data_name                   matlab.ui.control.EditField
        plot_VT                     matlab.ui.control.Button
        plot_XT                     matlab.ui.control.Button
        ControllertweakingPanel     matlab.ui.container.Panel
        CTPC                        matlab.ui.control.NumericEditField
        CTPCEditFieldLabel          matlab.ui.control.Label
        PCTC                        matlab.ui.control.NumericEditField
        PCTCEditFieldLabel          matlab.ui.control.Label
        IGXEditFieldLabel           matlab.ui.control.Label
        IGX                         matlab.ui.control.NumericEditField
        DriveTab                    matlab.ui.container.Tab
        Run                         matlab.ui.control.Button
        Speed_strLabel              matlab.ui.control.Label
        Speed_str                   matlab.ui.control.NumericEditField
        CollisionDetection          matlab.ui.control.CheckBox
        AddButton                   matlab.ui.control.Button
        LocationEditFieldLabel      matlab.ui.control.Label
        Location                    matlab.ui.control.EditField
        OrderTextAreaLabel          matlab.ui.control.Label
        Order                       matlab.ui.control.TextArea
        Clear                       matlab.ui.control.Button
        RuntimeEditFieldLabel_2     matlab.ui.control.Label
        Runtime_2                   matlab.ui.control.NumericEditField
        FieldLabel                  matlab.ui.control.Label
        FieldNr                     matlab.ui.control.Spinner
        Speed_trnLabel              matlab.ui.control.Label
        Speed_trn                   matlab.ui.control.NumericEditField
        Pulse_timeEditFieldLabel    matlab.ui.control.Label
        Pulse_time                  matlab.ui.control.NumericEditField
        BRK_FEditFieldLabel         matlab.ui.control.Label
        BRK_F                       matlab.ui.control.NumericEditField
        ProfileDropDownLabel        matlab.ui.control.Label
        ProfileDropDown             matlab.ui.control.DropDown
        DashboardButton             matlab.ui.control.StateButton
        StopBeaconButton            matlab.ui.control.Button
    end


    properties (Access = public)
        handles %property storing handle to commen file. Do not delete
        mainfig
    end
    
        properties (Access = private)
            location_vec = [0];
        end

    methods (Access = public)
    
        function disp(app,string)
            %content of this function may be deleted but not the function itself;
            app.Console.Value = [string;app.Console.Value];
        end
    
        function update(app,source)
            if app.handles.update_gui
                %spesific updates depending on function caller
                %more cases may need to be added later to stay compatible
                %with different classes
                switch source
                    case 'COMM'
                       
                    case 'KITT'
                        
                end
              
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            if nargin ~=2
                %app can not start without a parent
                delete(app)
            else
                app.handles = parent;
                parent.gui_available = 1;
                app.mainfig = app.ChallengesUIFigure;
                if app.handles.comm.connected || app.handles.comm.vir_con
                    app.ConnectBT.Value = true;
                    app.Comport.Value = str2num(app.handles.comm.comport);
                    app.Comport.Enable = 'off';
                end
                app.CTPC.Value = app.handles.kitt.ctpc*1000;
                app.PCTC.Value = app.handles.kitt.pctc*1000;
                app.IGX.Value = app.handles.kitt.sw_dis;
           %     load(app.handles.field,app.FieldNr.Value)
            end 
        end

        % Close request function: ChallengesUIFigure
        function ChallengesUIFigureCloseRequest(app, event)
 
            delete(app.handles)            
        end

        % Button pushed function: ChangeGUI
        function ChangeGUIButtonPushed(app, event)
            app.handles.gui_available = 0;
            stardup_gui(app.handles);
            delete(app);
        end

        % Value changed function: ConnectBT
        function ConnectBTValueChanged(app, event)
            value = app.ConnectBT.Value;
            app.ConnectBT.Enable = 'off';
            app.Comport.Enable = 'off';
            %gui only updates during downtime
            pause(.01)
            switch value
                case 1
                    result = open(app.handles.comm,app.Comport.Value);
                    if ~result
                        app.ConnectBT.Value = 0;
                        app.Comport.Enable = 'on';
                    end
                case 0
                    close(app.handles.comm)
                    app.Comport.Enable = 'on';
            end
            app.ConnectBT.Enable = 'on'; 
        end

        % Value changed function: ProfileDropDown
        function ProfileDropDownValueChanged(app, event)
            value = app.ProfileDropDown.Value;
            load = 1;
            switch value
                case 'Profile 1'
                    name = 'profile1';
                case 'Profile 2'
                    name = 'profile2';
                case 'Profile 3'
                    name = 'profile3';
                case 'Profile 4'
                    name = 'profile4';
                case 'Profile 5'
                    name = 'profile5';
                case 'Select Profile'
                    load = 0;
                    app.handles.kitt.profile_loaded = 0;
                    
            end
            if load
                result = load_profile(app.handles.kitt,name)
            end
            if ~result
                app.ProfileDropDown.Value = 'Select Profile';
            end
        end

        % Value changed function: CTPC, IGX, PCTC
        function IGXValueChanged(app, event)
            app.handles.kitt.ctpc = app.CTPC.Value*1e-3;
            app.handles.kitt.pctc = app.PCTC.Value*1e-3;
            app.handles.kitt.sw_dis = app.IGX.Value;
        end

        % Button pushed function: Save_data
        function Save_dataButtonPushed(app, event)
            if app.handles.data_acq(2)
                save_datav(app.handles, app.data_name.Value)
            else
                disp(app.handles, 'Data acquisition not enabled');
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.Runtime.Value = wall_break(app.handles.kitt, app.Startpower.Value, app.Breakpower.Value, app.stopdistance.Value*1e-2);
        end

        % Button pushed function: plot_VT
        function plot_VTPushed(app, event)
            data = app.handles.kitt.datav(1:app.handles.kitt.n,:);
            t = data(:,1) - app.handles.t0;
            figure; plot(t,data(:,3));
            hold on
            plot(t,data(:,5));
            plot(t,data(:,7));
            hold off
            legend('extrapolated','measured','used');
            title('Vt plot')
            xlabel('time(s)')
            ylabel('speed(m/s)')
        end

        % Button pushed function: plot_XT
        function plot_XTButtonPushed(app, event)
            data = app.handles.kitt.datav(1:app.handles.kitt.n,:);
            t = data(:,1) -  app.handles.t0;
            figure; plot(t,data(:,2));
            hold on
            plot(t,data(:,4));
            plot(t,data(:,6));
            hold off
            legend('extrapolated','measured','used');
            title('Xt plot')
            xlabel('time(s)')
            ylabel('distance(m)')
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            added = app.Location.Value;
            switch added
                case 'A'
                    app.location_vec = [app.location_vec;1];
                    app.Order.Value = [app.Order.Value;added];
                case 'B'
                    app.location_vec = [app.location_vec;2];
                    app.Order.Value = [app.Order.Value;added];
                case 'C'
                    app.location_vec = [app.location_vec;3];
                    app.Order.Value = [app.Order.Value;added];
                case 'D'
                    app.location_vec = [app.location_vec;4];
                    app.Order.Value = [app.Order.Value;added];
                otherwise
                    disp(app.handles, 'Location not recognized');
                    disp(app.handles, 'Add uppercase A/B/C/D');
            end
            app.Location.Value = '';
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
                 app.location_vec = [0];
                 app.Order.Value = ['Start'];
        end

        % Button pushed function: Run
        function RunPushed(app, event)
           %drive(,  ,, );
           if size(app.location_vec) < 2
              disp(app.handles,'No route defined');
              return 
           end
           app.Runtime_2.Value = drive(app.handles.kitt, app.location_vec(2:end), app.CollisionDetection.Value, app.Speed_str.Value, app.Speed_trn.Value, app.Pulse_time.Value, app.BRK_F.Value);
        end

        % Value changed function: FieldNr
        function FieldNrValueChanged(app, event)
            value = app.FieldNr.Value;
            load(app.handles.field,value);
        end

        % Button pushed function: StopBeaconButton
        function StopBeaconButtonPushed(app, event)
            send(app.handles.comm, {'A0'});
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChallengesUIFigure = uifigure;
            app.ChallengesUIFigure.Name = 'Challenges';
            app.ChallengesUIFigure.CloseRequestFcn = createCallbackFcn(app, @ChallengesUIFigureCloseRequest, true);
            app.ChangeGUI = uibutton(app.ChallengesUIFigure, 'push');
            app.ChangeGUI.ButtonPushedFcn = createCallbackFcn(app, @ChangeGUIButtonPushed, true);
            app.ChangeGUI.Text = 'Change GUI';
            app.ConsoleLabel = uilabel(app.ChallengesUIFigure);
            app.ConsoleLabel.Text = 'Console';
            app.Console = uitextarea(app.ChallengesUIFigure);
            app.Console.Editable = 'off';
            app.Console.Value = {'Started'};
            app.ConnectBT = uibutton(app.ChallengesUIFigure, 'state');
            app.ConnectBT.ValueChangedFcn = createCallbackFcn(app, @ConnectBTValueChanged, true);
            app.ConnectBT.Text = 'Connect';
            app.ComportEditFieldLabel = uilabel(app.ChallengesUIFigure);
            app.ComportEditFieldLabel.Text = 'Comport';
            app.Comport = uieditfield(app.ChallengesUIFigure, 'numeric');
            app.Comport.RoundFractionalValues = 'on';
            app.Comport.Value = 4;
            app.TabGroup = uitabgroup(app.ChallengesUIFigure);
            app.WallrunTab = uitab(app.TabGroup);
            app.WallrunTab.Title = 'Wall run';
            app.SetupPanel = uipanel(app.WallrunTab);
            app.SetupPanel.Title = 'Setup';
            app.GoButton = uibutton(app.SetupPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.StartpowerEditFieldLabel = uilabel(app.SetupPanel);
            app.StartpowerEditFieldLabel.Text = 'Start power';
            app.Startpower = uieditfield(app.SetupPanel, 'numeric');
            app.Startpower.Limits = [155 165];
            app.Startpower.Value = 165;
            app.BreakpowerEditFieldLabel = uilabel(app.SetupPanel);
            app.BreakpowerEditFieldLabel.Text = 'Break power';
            app.Breakpower = uieditfield(app.SetupPanel, 'numeric');
            app.Breakpower.Limits = [135 145];
            app.Breakpower.Value = 135;
            app.stopdistanceEditFieldLabel = uilabel(app.SetupPanel);
            app.stopdistanceEditFieldLabel.Text = 'stop distance';
            app.stopdistance = uieditfield(app.SetupPanel, 'numeric');
            app.stopdistance.Limits = [10 60];
            app.stopdistance.ValueDisplayFormat = '%11.4g cm';
            app.stopdistance.Value = 60;
            app.RuntimeEditFieldLabel = uilabel(app.SetupPanel);
            app.RuntimeEditFieldLabel.Text = 'Run time';
            app.Runtime = uieditfield(app.SetupPanel, 'numeric');
            app.Panel = uipanel(app.WallrunTab);
            app.Panel.Title = 'Panel';
            app.Save_data = uibutton(app.Panel, 'push');
            app.Save_data.ButtonPushedFcn = createCallbackFcn(app, @Save_dataButtonPushed, true);
            app.Save_data.Text = 'Save';
            app.data_name = uieditfield(app.Panel, 'text');
            app.plot_VT = uibutton(app.Panel, 'push');
            app.plot_VT.ButtonPushedFcn = createCallbackFcn(app, @plot_VTPushed, true);
            app.plot_VT.Text = 'VT';
            app.plot_XT = uibutton(app.Panel, 'push');
            app.plot_XT.ButtonPushedFcn = createCallbackFcn(app, @plot_XTButtonPushed, true);
            app.plot_XT.Text = 'XT';
            app.ControllertweakingPanel = uipanel(app.WallrunTab);
            app.ControllertweakingPanel.Title = 'Controller tweaking';
            app.CTPC = uieditfield(app.ControllertweakingPanel, 'numeric');
            app.CTPC.RoundFractionalValues = 'on';
            app.CTPC.ValueDisplayFormat = '%11.4g ms';
            app.CTPC.ValueChangedFcn = createCallbackFcn(app, @IGXValueChanged, true);
            app.CTPC.Value = 70;
            app.CTPCEditFieldLabel = uilabel(app.ControllertweakingPanel);
            app.CTPCEditFieldLabel.Text = 'CTPC';
            app.PCTC = uieditfield(app.ControllertweakingPanel, 'numeric');
            app.PCTC.RoundFractionalValues = 'on';
            app.PCTC.ValueDisplayFormat = '%11.4g ms';
            app.PCTC.ValueChangedFcn = createCallbackFcn(app, @IGXValueChanged, true);
            app.PCTC.Value = 300;
            app.PCTCEditFieldLabel = uilabel(app.ControllertweakingPanel);
            app.PCTCEditFieldLabel.Text = 'PCTC';
            app.IGXEditFieldLabel = uilabel(app.ControllertweakingPanel);
            app.IGXEditFieldLabel.Text = 'IG X';
            app.IGX = uieditfield(app.ControllertweakingPanel, 'numeric');
            app.IGX.ValueDisplayFormat = '%11.4g m';
            app.IGX.ValueChangedFcn = createCallbackFcn(app, @IGXValueChanged, true);
            app.IGX.Value = 3;
            app.DriveTab = uitab(app.TabGroup);
            app.DriveTab.Title = 'Drive';
            app.Run = uibutton(app.DriveTab, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunPushed, true);
            app.Run.Text = 'Run';
            app.Speed_strLabel = uilabel(app.DriveTab);
            app.Speed_strLabel.Text = 'Speed_str';
            app.Speed_str = uieditfield(app.DriveTab, 'numeric');
            app.Speed_str.Limits = [155 165];
            app.Speed_str.Value = 159;
            app.CollisionDetection = uicheckbox(app.DriveTab);
            app.CollisionDetection.Text = 'Collision Detection';
            app.AddButton = uibutton(app.DriveTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.LocationEditFieldLabel = uilabel(app.DriveTab);
            app.LocationEditFieldLabel.Text = 'Location';
            app.Location = uieditfield(app.DriveTab, 'text');
            app.OrderTextAreaLabel = uilabel(app.DriveTab);
            app.OrderTextAreaLabel.Text = 'Order';
            app.Order = uitextarea(app.DriveTab);
            app.Order.Editable = 'off';
            app.Order.Value = {'Start'};
            app.Clear = uibutton(app.DriveTab, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear';
            app.RuntimeEditFieldLabel_2 = uilabel(app.DriveTab);
            app.RuntimeEditFieldLabel_2.Text = 'Run time';
            app.Runtime_2 = uieditfield(app.DriveTab, 'numeric');
            app.FieldLabel = uilabel(app.DriveTab);
            app.FieldLabel.Text = 'Field';
            app.FieldNr = uispinner(app.DriveTab);
            app.FieldNr.Limits = [1 5];
            app.FieldNr.ValueChangedFcn = createCallbackFcn(app, @FieldNrValueChanged, true);
            app.FieldNr.Value = 1;
            app.Speed_trnLabel = uilabel(app.DriveTab);
            app.Speed_trnLabel.Text = 'Speed_trn';
            app.Speed_trn = uieditfield(app.DriveTab, 'numeric');
            app.Speed_trn.Limits = [155 165];
            app.Speed_trn.Value = 159;
            app.Pulse_timeEditFieldLabel = uilabel(app.DriveTab);
            app.Pulse_timeEditFieldLabel.Text = 'Pulse_time';
            app.Pulse_time = uieditfield(app.DriveTab, 'numeric');
            app.BRK_FEditFieldLabel = uilabel(app.DriveTab);
            app.BRK_FEditFieldLabel.Text = 'BRK_F';
            app.BRK_F = uieditfield(app.DriveTab, 'numeric');
            app.BRK_F.Value = 140;
            app.ProfileDropDownLabel = uilabel(app.ChallengesUIFigure);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.ChallengesUIFigure);
            app.ProfileDropDown.Items = {'Select Profile', 'Profile 1', 'Profile 2', 'Profile 3', 'Profile 4', 'Profile 5'};
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileDropDownValueChanged, true);
            app.ProfileDropDown.Value = 'Select Profile';
            app.DashboardButton = uibutton(app.ChallengesUIFigure, 'state');
            app.DashboardButton.Text = 'Dashboard';
            app.StopBeaconButton = uibutton(app.ChallengesUIFigure, 'push');
            app.StopBeaconButton.ButtonPushedFcn = createCallbackFcn(app, @StopBeaconButtonPushed, true);
            app.StopBeaconButton.Text = 'Stop Beacon';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Challenges(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChallengesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChallengesUIFigure)
        end
    end
end