classdef field_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Console                  matlab.ui.control.TextArea
        OtherPanel               matlab.ui.container.Panel
        ChangeGUI                matlab.ui.control.Button
        ConnectBT                matlab.ui.control.StateButton
        ComportEditFieldLabel    matlab.ui.control.Label
        Comport                  matlab.ui.control.NumericEditField
        MakefieldPanel           matlab.ui.container.Panel
        Makefield                matlab.ui.control.Button
        RatioEditFieldLabel      matlab.ui.control.Label
        Ratio                    matlab.ui.control.NumericEditField
        SetupNameEditFieldLabel  matlab.ui.control.Label
        SetupName                matlab.ui.control.EditField
        SaveButton               matlab.ui.control.Button
        SaveNrSpinnerLabel       matlab.ui.control.Label
        SaveNr                   matlab.ui.control.Spinner
        AddDestinationsPanel     matlab.ui.container.Panel
        DropDown                 matlab.ui.control.DropDown
        ShowField                matlab.ui.control.StateButton
        TakepositionButton_2     matlab.ui.control.Button
    end


    properties (Access = public)
        handles %property storing handle to commen file. Do not delete
        mainfig
    end
    
    properties (Access = private)
            distance_mat
            field_ex = 0;
        
            field
            mic
            hdn_pos
            points = zeros(5,2);
            ratio
            
            map
    end

    methods (Access = public)
    
        function disp(app,string)
            %content of this function may be deleted but not the function itself;
            app.Console.Value = [string;app.Console.Value];
        end
    
        function update(app,source)
            if app.handles.update_gui
                %spesific updates depending on function caller
                %more cases may need to be added later to stay compatible
                %with different classes
                switch source
                    case 'COMM'
                       
                    case 'KITT'
                        
                end
              
            end
        end
    end

    methods (Access = private)
    
       function plotCloseRequest(app, ~)
            app.map.active = 0;
            delete(app.map.fig)
       end
        
        function plot_fig(app)
                if ~app.map.active
                    app.map.fig = figure;
                    app.map.active = 1;
                    app.map.axes = subplot(1,1,1);
                end
                axes(app.map.axes);
                imagesc(app.field)
                colormap(flipud(gray));
                hold on
                labels = {'A','B','C','D','Start','Heading'};
                plot([app.points(:,1);app.hdn_pos(1)],[app.points(:,2);app.hdn_pos(2)],'o')
                text([app.points(:,1);app.hdn_pos(1)],[app.points(:,2);app.hdn_pos(2)],labels, 'VerticalAlignment','bottom','HorizontalAlignment','right')
                hold off
                app.map.fig.CloseRequestFcn = createCallbackFcn(app, @plotCloseRequest, true);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            if nargin ~=2
                %app can not start without a parent
                delete(app)
            else
                app.handles = parent;
                parent.gui_available = 1;
                %Edit do let it point to main UIFIG
                %used to display loading bars etc;
                app.mainfig = app.UIFigure;
                %common comport connection acros all gui's
                if app.handles.comm.connected || app.handles.comm.vir_con
                    app.ConnectBT.Value = true;
                    app.Comport.Value = str2num(app.handles.comm.comport);
                    app.Comport.Enable = 'off';
                end
                app.map.active = 0;
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.handles)            
        end

        % Button pushed function: ChangeGUI
        function ChangeGUIButtonPushed(app, event)
            app.handles.gui_available = 0;
            stardup_gui(app.handles);
            delete(app);
        end

        % Value changed function: ConnectBT
        function ConnectBTValueChanged(app, event)
            value = app.ConnectBT.Value;
            app.ConnectBT.Enable = 'off';
            app.Comport.Enable = 'off';
            %gui only updates during downtime
            pause(.01)
            switch value
                case 1
                    result = open(app.handles.comm,app.Comport.Value);
                    if ~result
                        app.ConnectBT.Value = 0;
                        app.Comport.Enable = 'on';
                    end
                case 0
                    close(app.handles.comm)
                    app.Comport.Enable = 'on';
            end
            app.ConnectBT.Enable = 'on'; 
        end

        % Button pushed function: Makefield
        function MakefieldPushed(app, event)
            app.field_ex = 0;
            try
                name = app.SetupName.Value;
                load(name, 'MIC', 'order', 'prim');
                [app.field,app.mic] = create_map(MIC, app.Ratio.Value, order);
               % app.mic = [app.mic,MIC(:,3).*app.Ratio.Value];
                 app.points = zeros(5,2);
                 app.hdn_pos = [0 0];
            catch
                disp(app.handles,'failed making field')
                return;
            end
            disp(app.handles,'field created');
            app.field_ex = 1;
            app.ratio = app.Ratio.Value;
        end

        % Callback function
        function TakepositionButtonPushed(app, event)
            value = app.MICSpinner.Value;
            
            
            app.distance_mat(value,:) = 0;
        end

        % Button pushed function: TakepositionButton_2
        function TakepositionButton_2Pushed(app, event)
            if ~app.field_ex
                disp(app.handles,'no field exists');
               return 
            end
            value = app.DropDown.Value;
            hdng = 0;
            switch value
                case 'A'
                    ind = 1;
                case 'B'
                    ind = 2;
                case 'C'
                    ind = 3;
                case 'D'
                    ind = 4;
                case 'Start'
                    ind = 5;
                case 'Heading'
                    hdng = 1;
            end
            
            if app.handles.comm.connected
                audio = app.handles.audio;
                
                send(app.handles.comm,{strcat('B',num2str(audio.f_b,'%d'));
                                    strcat('F', num2str(audio.f_c,'%d'));
                                    strcat('R', num2str(audio.c_r,'%d'));
                                    strcat('C0x', audio.code)});     

                pause(.5)
                send(app.handles.comm, {'A1'});
                [y_data] = record(audio.Fs,audio.nsamples);
                [D] = TDOA2(y_data,audio.ref,audio.pulse_rate);
                location = round(lst_sq_pos3D(D, app.handles.field.Mic, 8.5*app.handles.field.Ratio, .3*app.handles.field.Ratio));
                send(app.handles.comm, {'A0'});
            elseif app.handles.comm.vir_con
                   plot_fig(app);
                axes(app.map.axes);
                try
                [x,y] = ginput(1);
                catch
                    return  
                end
                location = [x,y];
            else 
                disp(app.handles,'connect to the car first');
                return;
            end
            
            if hdng
                app.hdn_pos = location;
            else
                app.points(ind,:) = location;
            end
            
            plot_fig(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~app.field_ex
                disp(app.handles,'no field exists');
               return 
            end
            name = num2str(app.SaveNr.Value);
            location = strcat(app.handles.main_path, app.handles.folders{2}, '\', 'field',name);
            
            Field_s = app.field;
            MIC_s = app.mic;
            Heading_s = angl([app.points(5,:);app.hdn_pos]);
            Points_s = round(app.points);
            Ratio_s = app.ratio;
            
            save(location, 'Field_s', 'MIC_s', 'Heading_s', 'Points_s', 'Ratio_s');
        end

        % Value changed function: ShowField
        function ShowFieldValueChanged(app, event)
            value = app.ShowField.Value;
            if value
                    if app.field_ex
                        plot_fig(app);
                    else
                        disp(app.handles,'no field exists');
                        app.ShowField.Value = 0;
                    end
            else
                close(app.map.fig)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Console = uitextarea(app.UIFigure);
            app.Console.Value = {'Started'};
            app.OtherPanel = uipanel(app.UIFigure);
            app.OtherPanel.Title = 'Other';
            app.ChangeGUI = uibutton(app.OtherPanel, 'push');
            app.ChangeGUI.ButtonPushedFcn = createCallbackFcn(app, @ChangeGUIButtonPushed, true);
            app.ChangeGUI.Text = 'Change GUI';
            app.ConnectBT = uibutton(app.OtherPanel, 'state');
            app.ConnectBT.ValueChangedFcn = createCallbackFcn(app, @ConnectBTValueChanged, true);
            app.ConnectBT.Text = 'Connect';
            app.ComportEditFieldLabel = uilabel(app.OtherPanel);
            app.ComportEditFieldLabel.Text = 'Comport';
            app.Comport = uieditfield(app.OtherPanel, 'numeric');
            app.Comport.RoundFractionalValues = 'on';
            app.Comport.Value = 4;
            app.MakefieldPanel = uipanel(app.UIFigure);
            app.MakefieldPanel.Title = 'Make field';
            app.Makefield = uibutton(app.MakefieldPanel, 'push');
            app.Makefield.ButtonPushedFcn = createCallbackFcn(app, @MakefieldPushed, true);
            app.Makefield.Text = 'Make field';
            app.RatioEditFieldLabel = uilabel(app.MakefieldPanel);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.Ratio = uieditfield(app.MakefieldPanel, 'numeric');
            app.Ratio.Limits = [1 Inf];
            app.Ratio.Value = 50;
            app.SetupNameEditFieldLabel = uilabel(app.MakefieldPanel);
            app.SetupNameEditFieldLabel.Text = 'Setup Name';
            app.SetupName = uieditfield(app.MakefieldPanel, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveNrSpinnerLabel = uilabel(app.UIFigure);
            app.SaveNrSpinnerLabel.Text = 'Save.Nr';
            app.SaveNr = uispinner(app.UIFigure);
            app.SaveNr.Limits = [1 5];
            app.SaveNr.Value = 1;
            app.AddDestinationsPanel = uipanel(app.UIFigure);
            app.AddDestinationsPanel.Title = 'Add Destinations';
            app.DropDown = uidropdown(app.AddDestinationsPanel);
            app.DropDown.Items = {'Start', 'Heading', 'A', 'B', 'C', 'D'};
            app.DropDown.Value = 'Start';
            app.ShowField = uibutton(app.AddDestinationsPanel, 'state');
            app.ShowField.ValueChangedFcn = createCallbackFcn(app, @ShowFieldValueChanged, true);
            app.ShowField.Text = 'Show Field';
            app.TakepositionButton_2 = uibutton(app.AddDestinationsPanel, 'push');
            app.TakepositionButton_2.ButtonPushedFcn = createCallbackFcn(app, @TakepositionButton_2Pushed, true);
            app.TakepositionButton_2.Text = 'Take position';
        end
    end

    methods (Access = public)

        % Construct app
        function app = field_gui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end