classdef friction_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SpeedForcePanel      matlab.ui.container.Panel
        ForceLabel           matlab.ui.control.Label
        SpeedLabel           matlab.ui.control.Label
        Label                matlab.ui.control.Label
        F151                 matlab.ui.control.NumericEditField
        V151                 matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        F152                 matlab.ui.control.NumericEditField
        V152                 matlab.ui.control.NumericEditField
        Label_3              matlab.ui.control.Label
        F153                 matlab.ui.control.NumericEditField
        V153                 matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        F154                 matlab.ui.control.NumericEditField
        V154                 matlab.ui.control.NumericEditField
        Label_5              matlab.ui.control.Label
        F155                 matlab.ui.control.NumericEditField
        V155                 matlab.ui.control.NumericEditField
        Label_6              matlab.ui.control.Label
        F156                 matlab.ui.control.NumericEditField
        V156                 matlab.ui.control.NumericEditField
        Label_7              matlab.ui.control.Label
        F157                 matlab.ui.control.NumericEditField
        V157                 matlab.ui.control.NumericEditField
        Label_8              matlab.ui.control.Label
        F158                 matlab.ui.control.NumericEditField
        V158                 matlab.ui.control.NumericEditField
        Label_9              matlab.ui.control.Label
        F159                 matlab.ui.control.NumericEditField
        V159                 matlab.ui.control.NumericEditField
        Label_10             matlab.ui.control.Label
        F160                 matlab.ui.control.NumericEditField
        V160                 matlab.ui.control.NumericEditField
        Label_11             matlab.ui.control.Label
        F161                 matlab.ui.control.NumericEditField
        V161                 matlab.ui.control.NumericEditField
        Label_12             matlab.ui.control.Label
        F162                 matlab.ui.control.NumericEditField
        V162                 matlab.ui.control.NumericEditField
        Label_13             matlab.ui.control.Label
        F163                 matlab.ui.control.NumericEditField
        V163                 matlab.ui.control.NumericEditField
        Label_14             matlab.ui.control.Label
        F164                 matlab.ui.control.NumericEditField
        V164                 matlab.ui.control.NumericEditField
        Label_15             matlab.ui.control.Label
        F165                 matlab.ui.control.NumericEditField
        V165                 matlab.ui.control.NumericEditField
        SaveButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        AEditFieldLabel      matlab.ui.control.Label
        Acoef                matlab.ui.control.NumericEditField
        BEditFieldLabel      matlab.ui.control.Label
        Bcoef                matlab.ui.control.NumericEditField
        CEditFieldLabel      matlab.ui.control.Label
        Ccoef                matlab.ui.control.NumericEditField
        FdABVCV2Label        matlab.ui.control.Label
        FitButton            matlab.ui.control.Button
        ExportButton         matlab.ui.control.Button
        LinspaceForceButton  matlab.ui.control.Button
    end


    properties (Access = private)
        parent
    end

    methods (Access = private)    
        function [F] = get_force(app)
          F = [0,   app.F151.Value, app.F152.Value, app.F153.Value, app.F154.Value, app.F155.Value,...
                    app.F156.Value, app.F157.Value, app.F158.Value, app.F159.Value, app.F160.Value,...
                    app.F161.Value, app.F162.Value, app.F163.Value, app.F164.Value, app.F165.Value];
        end
    
        function set_force(app, F)
                tmp = num2cell(F);
               [app.F151.Value, app.F152.Value, app.F153.Value, app.F154.Value, app.F155.Value,...
                app.F156.Value, app.F157.Value, app.F158.Value, app.F159.Value, app.F160.Value,...
                app.F161.Value, app.F162.Value, app.F163.Value, app.F164.Value, app.F165.Value] = deal(tmp{:});
        end
    
        function [V] = get_speed(app)
           V = [0,  app.V151.Value, app.V152.Value, app.V153.Value, app.V154.Value, app.V155.Value,...
                    app.V156.Value, app.V157.Value, app.V158.Value, app.V159.Value, app.V160.Value,...
                    app.V161.Value, app.V162.Value, app.V163.Value, app.V164.Value, app.V165.Value];
        end
    
        function set_speed(app,V)
                tmp = num2cell(V);
               [app.V151.Value, app.V152.Value, app.V153.Value, app.V154.Value, app.V155.Value,...
                app.V156.Value, app.V157.Value, app.V158.Value, app.V159.Value, app.V160.Value,...
                app.V161.Value, app.V162.Value, app.V163.Value, app.V164.Value, app.V165.Value] = deal(tmp{:});
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            if nargin == 2
            app.parent = parent;
            end
                set_speed(app,app.parent.V(2:16));
                set_force(app,app.parent.F(2:16));
        end

        % Button pushed function: LinspaceForceButton
        function LinspaceForceButtonPushed(app, event)
            tmp = linspace(0, app.parent.F_max.Value, 16);
            set_force(app, tmp(2:16));
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            x = get_speed(app);
            y = get_force(app);
            plot(app.UIAxes, x,y,'*');
            
            %try
            fitobj = fit(x', y', 'poly2');
            %assignin('base','fitobj',fitobj)
            
            coeffvals = coeffvalues(fitobj);
            app.Acoef.Value = coeffvals(3);
            app.Bcoef.Value = coeffvals(2);
            app.Ccoef.Value = coeffvals(1);
            
            xaxis = linspace(0,max(x),500);
            fitplot = fitobj(xaxis);

            plot(app.UIAxes, x, y,'*');
            hold(app.UIAxes,'on');
            plot(app.UIAxes, xaxis, fitplot);
            hold(app.UIAxes,'on');
            %catch
              % disp(app.parent.handles, 'impossible fit');
            %end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.parent.Acoef.Value = app.Acoef.Value;
            app.parent.Bcoef.Value = app.Bcoef.Value;
            app.parent.Ccoef.Value = app.Ccoef.Value;
            app.parent.F_max.Value = app.F165.Value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.parent.F = get_force(app);
            app.parent.V = get_speed(app);
           % save(strcat(app.parent.handles.main_path, app.parent.handles.folders{1},'\FV_measurements'),'F', 'V');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SpeedForcePanel = uipanel(app.UIFigure);
            app.SpeedForcePanel.Title = 'Speed Force ';
            app.ForceLabel = uilabel(app.SpeedForcePanel);
            app.ForceLabel.Text = 'Force';
            app.SpeedLabel = uilabel(app.SpeedForcePanel);
            app.SpeedLabel.Text = 'Speed';
            app.Label = uilabel(app.SpeedForcePanel);
            app.Label.Text = '151';
            app.F151 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V151 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_2 = uilabel(app.SpeedForcePanel);
            app.Label_2.Text = '152';
            app.F152 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V152 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_3 = uilabel(app.SpeedForcePanel);
            app.Label_3.Text = '153';
            app.F153 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V153 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_4 = uilabel(app.SpeedForcePanel);
            app.Label_4.Text = '154';
            app.F154 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V154 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_5 = uilabel(app.SpeedForcePanel);
            app.Label_5.Text = '155';
            app.F155 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V155 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_6 = uilabel(app.SpeedForcePanel);
            app.Label_6.Text = '156';
            app.F156 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V156 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_7 = uilabel(app.SpeedForcePanel);
            app.Label_7.Text = '157';
            app.F157 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V157 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_8 = uilabel(app.SpeedForcePanel);
            app.Label_8.Text = '158';
            app.F158 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V158 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_9 = uilabel(app.SpeedForcePanel);
            app.Label_9.Text = '159';
            app.F159 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V159 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_10 = uilabel(app.SpeedForcePanel);
            app.Label_10.Text = '160';
            app.F160 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V160 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_11 = uilabel(app.SpeedForcePanel);
            app.Label_11.Text = '161';
            app.F161 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V161 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_12 = uilabel(app.SpeedForcePanel);
            app.Label_12.Text = '162';
            app.F162 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V162 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_13 = uilabel(app.SpeedForcePanel);
            app.Label_13.Text = '163';
            app.F163 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V163 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_14 = uilabel(app.SpeedForcePanel);
            app.Label_14.Text = '164';
            app.F164 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V164 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.Label_15 = uilabel(app.SpeedForcePanel);
            app.Label_15.Text = '165';
            app.F165 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.V165 = uieditfield(app.SpeedForcePanel, 'numeric');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Force')
            ylabel(app.UIAxes, 'Speed')
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.Acoef = uieditfield(app.UIFigure, 'numeric');
            app.Acoef.Editable = 'off';
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.Bcoef = uieditfield(app.UIFigure, 'numeric');
            app.Bcoef.Editable = 'off';
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.Ccoef = uieditfield(app.UIFigure, 'numeric');
            app.Ccoef.Editable = 'off';
            app.FdABVCV2Label = uilabel(app.UIFigure);
            app.FdABVCV2Label.Text = 'Fd = A + B*V + C*V^2';
            app.FitButton = uibutton(app.UIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.LinspaceForceButton = uibutton(app.UIFigure, 'push');
            app.LinspaceForceButton.ButtonPushedFcn = createCallbackFcn(app, @LinspaceForceButtonPushed, true);
            app.LinspaceForceButton.Text = {'Linspace'; 'Force'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = friction_calc(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end