classdef load_vars < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Profile               matlab.ui.control.DropDown
        LoadedvariablesPanel  matlab.ui.container.Panel
        BR_X                  matlab.ui.control.CheckBox
        BR_T                  matlab.ui.control.CheckBox
        A                     matlab.ui.control.CheckBox
        C_prop                matlab.ui.control.CheckBox
        B                     matlab.ui.control.CheckBox
        G                     matlab.ui.control.CheckBox
        L                     matlab.ui.control.CheckBox
        LoadButton            matlab.ui.control.Button
    end


    properties (Access = private)
        handles
        stored_names = {'BR_X_cell_s'; 'BR_T_cell_s'; 'A_cell_s'; 'B_cell_s'; 'G_cell_s'; 'L_cell_s'; 'prop'};

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            app.handles = parent;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            value = app.Profile.Value;
            selected = [app.BR_X.Value; app.BR_T.Value; app.A.Value; app.B.Value; app.G.Value; app.L.Value; app.C_prop.Value];
            
            switch value
                case'Profile 1'
                    load_name = 'profile1';
                    
                case'Profile 2'
                    load_name = 'profile2';
                       
                case'Profile 3'
                    load_name = 'profile3';
                            
                case'Profile 4'
                    load_name = 'profile4';
                    
                case'Profile 5'
                    load_name = 'profile5';
                
                case'Auto save'
                    load_name = 'auto_save';
                    
                otherwise
                    return;
            end
            load_path = strcat(app.handles.handles.main_path, app.handles.handles.folders{1}, '\', load_name);
            target_path = strcat(app.handles.handles.main_path, app.handles.handles.folders{1}, '\target');
            
            save_profile(app.handles, 'target')
            
            for k = 1:length(selected)
                if selected(k)
                    try
                        import_str = load(load_path, app.stored_names{k})                        
                    catch
                        disp(app.handles.handles, 'Profile/variable does not exist')
                        delete_profile(app.handles, 'target');
                        return;
                    end
                    import_val = getfield(import_str, app.stored_names{k});
                    copystruct.(app.stored_names{k}) = import_val;
                    save(target_path, '-struct', 'copystruct', app.stored_names{k}, '-append');
                    clear copystruct;
                    disp(app.handles.handles, strcat({app.stored_names{k}},{' loaded from profile '}, {load_name}));
                end
            end            
            load_profile(app.handles, 'target');            
            delete_profile(app.handles, 'target');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Profile = uidropdown(app.UIFigure);
            app.Profile.Items = {'Profile 1', 'Profile 2', 'Profile 3', 'Profile 4', 'Profile 5', 'Auto save'};
            app.Profile.Value = 'Profile 1';
            app.LoadedvariablesPanel = uipanel(app.UIFigure);
            app.LoadedvariablesPanel.Title = 'Loaded variables';
            app.BR_X = uicheckbox(app.LoadedvariablesPanel);
            app.BR_X.Text = 'BR_X';
            app.BR_T = uicheckbox(app.LoadedvariablesPanel);
            app.BR_T.Text = 'BR_T';
            app.A = uicheckbox(app.LoadedvariablesPanel);
            app.A.Text = 'A';
            app.C_prop = uicheckbox(app.LoadedvariablesPanel);
            app.C_prop.Text = 'Car properties';
            app.B = uicheckbox(app.LoadedvariablesPanel);
            app.B.Text = 'B';
            app.G = uicheckbox(app.LoadedvariablesPanel);
            app.G.Text = 'G';
            app.L = uicheckbox(app.LoadedvariablesPanel);
            app.L.Text = 'L';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
        end
    end

    methods (Access = public)

        % Construct app
        function app = load_vars(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end