classdef model_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ChangeGUI             matlab.ui.control.Button
        Console               matlab.ui.control.TextArea
        CarpropertiesPanel    matlab.ui.container.Panel
        F_maxEditFieldLabel   matlab.ui.control.Label
        F_max                 matlab.ui.control.NumericEditField
        massLabel             matlab.ui.control.Label
        mass                  matlab.ui.control.NumericEditField
        ALabel                matlab.ui.control.Label
        Acoef                 matlab.ui.control.NumericEditField
        BLabel                matlab.ui.control.Label
        Bcoef                 matlab.ui.control.NumericEditField
        CLabel                matlab.ui.control.Label
        Ccoef                 matlab.ui.control.NumericEditField
        DeterminefrictioncoefficientsButton  matlab.ui.control.Button
        ProfileDropDown       matlab.ui.control.DropDown
        SimulateButton        matlab.ui.control.Button
        FitsPanel             matlab.ui.container.Panel
        AButton               matlab.ui.control.Button
        BButton               matlab.ui.control.Button
        GButton               matlab.ui.control.Button
        LButton               matlab.ui.control.Button
        Br_TButton            matlab.ui.control.Button
        Br_XButton            matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
        TimingValuesPanel     matlab.ui.container.Panel
        CTPCEditFieldLabel    matlab.ui.control.Label
        CTPC                  matlab.ui.control.NumericEditField
        PCTCEditFieldLabel    matlab.ui.control.Label
        PCTC                  matlab.ui.control.NumericEditField
        EditField_2Label      matlab.ui.control.Label
        EditField_2           matlab.ui.control.NumericEditField
        EditField_3Label      matlab.ui.control.Label
        EditField_3           matlab.ui.control.NumericEditField
        fit_par               matlab.ui.container.Panel
        Save_fit              matlab.ui.control.Button
        SpanEditFieldLabel    matlab.ui.control.Label
        Span                  matlab.ui.control.NumericEditField
        Fit                   matlab.ui.control.CheckBox
        Data                  matlab.ui.control.CheckBox
        SimparametersPanel    matlab.ui.container.Panel
        VmaxEditFieldLabel    matlab.ui.control.Label
        Vmax                  matlab.ui.control.NumericEditField
        VstepsEditFieldLabel  matlab.ui.control.Label
        Vsteps                matlab.ui.control.NumericEditField
        FstepsEditFieldLabel  matlab.ui.control.Label
        Fsteps                matlab.ui.control.NumericEditField
        EditField_4Label      matlab.ui.control.Label
        EditField_4           matlab.ui.control.NumericEditField
        LoadFit               matlab.ui.control.Button
        LoadFit_2             matlab.ui.control.Button
    end


    properties (Access = public)
        handles
        
        F = zeros(1,16);
        V = zeros(1,16);
    end

    properties (Access = private)
        brake_cell
        acc_cell     
        save_name
        load_fit_window
        fit_plot
        fit_plot_active = 0;
        fit_plot_axes
        current_cell
        current_cell_name
        
        default = {'', '', '', '' ,''};
        BR_X_cell
        BR_T_cell
        A_cell
        B_cell
        G_cell
        L_cell
    end

    methods (Access = public)
    
        function disp(app, string)
            app.Console.Value = [string ; app.Console.Value];
        end
    
        function update(app,source)
            if app.handles.update_gui
                switch source
                    case 'COMM'
                        
                    case 'KITT'
                end
              
            end
        end
        
        function delete_profile(app, name)
            if nargin == 1
                name = app.save_name;
            end
            path = strcat(app.handles.main_path, app.handles.folders{1}, '\', name, '.mat');
            try
                delete(path);
            catch
                disp(app.handles, 'profile does not exist')
                return;
            end
             disp(app.handles, strcat({name}, {' deleted'}));
        end
        
    end

    methods (Access = private)
    
        function initial_fits(app, progress)  
            progress.Message = 'Creating initial fits';
            progress.Indeterminate = 'off';
            progress.Value = 0;            
            
            ft = fittype( 'loess' );
            opts = fitoptions( 'Method', 'LowessFit' );
            opts.Normalize = 'on';
            
            %Brake Distance Fit
            T = app.brake_cell{1,1};
            Vvec = app.brake_cell{1,2};
            brake_tabl = app.brake_cell{1,3};

            [xData, yData, zData] = prepareSurfaceData(T, Vvec, brake_tabl );
            opts.Span = 0.1;
            BR_Xfit = fit([xData, yData], zData, ft, opts);
            app.BR_X_cell = {xData, yData, zData, opts.Span, BR_Xfit};
            
            progress.Value = 1/6;            
            %Brake Time Fit
            T = app.brake_cell{1,1};
            Vvec = app.brake_cell{1,2};
            time_tabl = app.brake_cell{2,3};

            [xData, yData, zData] = prepareSurfaceData( T, Vvec, time_tabl);
            opts.Span = 0.05;
            BR_Tfit = fit([xData, yData], zData, ft, opts);
            app.BR_T_cell = {xData, yData, zData, opts.Span, BR_Tfit};
            
            progress.Value = 2/6;  
            %Acc A fit
            T = app.acc_cell{1,1};
            Vvec = app.acc_cell{1,2};
            A_tab = app.acc_cell{1,3};

            [xData, yData, zData] = prepareSurfaceData(T, Vvec, A_tab);
            opts.Span = 0.05;
            Afit = fit([xData, yData], zData, ft, opts);
            app.A_cell = {xData, yData, zData, opts.Span, Afit};
            
            
            progress.Value = 3/6;  
            %Acc B fit
            T = app.acc_cell{1,1};
            Vvec = app.acc_cell{1,2};
            B_tab = app.acc_cell{2,3};

            [xData, yData, zData] = prepareSurfaceData(T, Vvec, B_tab);
            opts.Span = 0.05;
            Bfit = fit( [xData, yData], zData, ft, opts);
            app.B_cell = {xData, yData, zData, opts.Span, Bfit};
            
            
            progress.Value = 4/6;  
            %Acc G fit
            T = app.acc_cell{1,1};
            Vvec = app.acc_cell{1,2};
            G_tab = app.acc_cell{1,4};

            [xData, yData, zData] = prepareSurfaceData(T, Vvec, G_tab);
            opts.Span = 0.01;
            Gfit = fit([xData, yData], zData, ft, opts);
            app.G_cell = {xData, yData, zData, opts.Span, Gfit};
            
            
            progress.Value = 5/6;  
            %Acc L fit
                T = app.acc_cell{1,1};
                Vvec = app.acc_cell{1,2};
                L_tab = app.acc_cell{2,4};

                [xData, yData, zData] = prepareSurfaceData(T, Vvec, L_tab);
                opts.Span = 0.05;
                Lfit = fit( [xData, yData], zData, ft, opts);
                app.L_cell = {xData, yData, zData, opts.Span, Lfit};

                
            progress.Value = 1;
        end
        
        function [x] = get_car_prop(app)
            x = [{app.F_max.Value}; {app.mass.Value}; {app.Acoef.Value}; {app.Bcoef.Value}; {app.Ccoef.Value}; {app.F}; {app.V}];
        end
    
        function set_car_prop(app, prop)
            try app.F_max.Value = prop{1}; catch; end
            try app.mass.Value = prop{2}; catch; end
            try app.Acoef.Value = prop{3}; catch; end
            try app.Bcoef.Value = prop{4}; catch; end
            try app.Ccoef.Value = prop{5}; catch; end
            try app.F = prop{6}; catch; app.F = zeros(1,16); end
            try app.V = prop{7}; catch; app.V = zeros(1,16); end
        end     

        function plotfit(app, zlab)
            app.fit_plot.fig = figure;
            app.fit_plot_active = 1;
            app.fit_plot.fig.CloseRequestFcn = createCallbackFcn(app, @plotCloseRequest, true);
            app.fit_par.Title = strcat(app.current_cell_name, ' fit parameters');
            app.Fit.Value = true;
            app.Data.Value = true;
            app.Span.Value = app.current_cell{4}*100;            
            app.fit_par.Visible = 'on';
            app.FitsPanel.Visible = 'off';
            figure(app.fit_plot.fig)
            app.fit_plot_axes = subplot(1,1,1);
            plot(app.current_cell{5}, [app.current_cell{1}, app.current_cell{2}], app.current_cell{3});
            xlabel(app.fit_plot_axes,'T')
            ylabel(app.fit_plot_axes,'Vvec')
            zlabel(app.fit_plot_axes,zlab)
            grid on
            
        end
    
        function plotCloseRequest(app, ~)
            app.fit_plot_active = 0;
            app.fit_par.Visible = 'off';
            app.FitsPanel.Visible = 'on';
            delete(app.fit_plot.fig)
        end

        function clear_programmer(app);
              app.BR_X_cell = app.default;
              app.BR_T_cell = app.default;
              app.A_cell = app.default;
              app.B_cell = app.default;
              app.G_cell = app.default;
              app.L_cell = app.default;
              prop = {0; 0; 0; 0; 0; zeros(1,16); zeros(1,16)};
              set_car_prop(app, prop);
        end
        
    end
    
    methods (Access = public)
       
        function [out] = load_profile(app, name)
            if nargin == 1
                name = app.save_name;
            end
            out = 1;
            path = strcat(app.handles.main_path, app.handles.folders{1}, '\', name);
            try
                load(path, 'BR_X_cell_s', 'BR_T_cell_s', 'A_cell_s', 'B_cell_s', 'G_cell_s', 'L_cell_s', 'prop');
            catch
                disp(app.handles, 'profile does not exist')
                out = 0;
                return;
            end
            set_car_prop(app, prop);
            app.BR_X_cell = BR_X_cell_s;
            app.BR_T_cell = BR_T_cell_s;
            app.A_cell = A_cell_s;
            app.B_cell = B_cell_s;
            app.G_cell = G_cell_s;
            app.L_cell = L_cell_s;
            disp(app.handles, strcat({name},{' loaded'}));
        end
        
        function save_profile(app, name)
            if nargin == 1;
                name = app.save_name;
            end
            location = strcat(app.handles.main_path, app.handles.folders{1}, '\', name);       
            prop = get_car_prop(app);
            BR_X_cell_s = app.BR_X_cell;
            BR_T_cell_s = app.BR_T_cell;
            A_cell_s = app.A_cell;
            B_cell_s = app.B_cell;
            G_cell_s = app.G_cell;
            L_cell_s = app.L_cell;
            try
                save(location, 'BR_X_cell_s', 'BR_T_cell_s', 'A_cell_s', 'B_cell_s', 'G_cell_s', 'L_cell_s', 'prop');
                disp(app.handles,strcat({'Programmer values saved to '},{name}));
            catch
               disp(app.handles,'Failed to save');
            end
        end
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            if nargin ~=2
                delete(app)
            else
                app.handles = parent;               
                loaded = load_profile(app, 'profile1');
                app.save_name = 'profile1';
                if ~loaded
                    app.BR_X_cell = app.default;
                    app.BR_T_cell = app.default;
                    app.A_cell = app.default;
                    app.B_cell = app.default;
                    app.G_cell = app.default;
                    app.L_cell = app.default; 
                end
                app.fit_par.Visible = 'off';
                app.handles.gui_available = 1;
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            answer = questdlg('This action will delete data that is not currently saved. Are you sure?','Confirm','Yes','Cancel','Cancel');
            if ~strcmp(answer,'Yes')
                return;
            end 
            delete(app.load_fit_window);
            if app.fit_plot_active
                close(app.fit_plot.fig);
            end
            delete(app.handles)            
        end

        % Button pushed function: ChangeGUI
        function ChangeGUIButtonPushed(app, event)
            app.handles.gui_available = 0;
            stardup_gui(app.handles);
            delete(app);
        end

        % Button pushed function: DeterminefrictioncoefficientsButton
        function DeterminefrictioncoefficientsButtonPushed(app, event)
            friction_calc(app);
        end

        % Value changed function: ProfileDropDown
        function ProfileDropDownValueChanged(app, event)
            value = app.ProfileDropDown.Value;
            answer = questdlg('Overwrite programmer values', 'Attention', 'Yes','No','No');

            app.SimulateButton.Enable = 'on';
            app.SaveButton.Enable = 'on';

            switch value
                case'Profile 1'
                    app.save_name = 'profile1';
                    
                case'Profile 2'
                    app.save_name = 'profile2';
                       
                case'Profile 3'
                    app.save_name = 'profile3';
                            
                case'Profile 4'
                    app.save_name = 'profile4';
                    
                case'Profile 5'
                    app.save_name = 'profile5'; 
                
                case'Auto save'
                    app.save_name = 'auto_save';
                    
                    app.SimulateButton.Enable = 'off';
                    app.SaveButton.Enable = 'off';               
                                        
            end
            if strcmp(answer,'Yes')
                load_profile(app);
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            progress = uiprogressdlg(app.UIFigure,'Title','Creating simulation fits');
            [app.brake_cell] = brake_fit(app.mass.Value, app.Acoef.Value, app.Bcoef.Value, app.Ccoef.Value, app.F_max.Value, app.Vmax.Value, app.Vsteps.Value, app.Fsteps.Value, progress);
            [app.acc_cell] = acc_fit(app.mass.Value, app.Acoef.Value, app.Bcoef.Value, app.Ccoef.Value, app.F_max.Value, app.Vmax.Value, app.Vsteps.Value, app.Fsteps.Value, progress);            
            initial_fits(app, progress);            
            save_profile(app,'auto_save');            
            pause(1);
            close(progress);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            save_profile(app, app.save_name);
        end

        % Button pushed function: LoadFit
        function LoadFitPushed(app, event)
            app.load_fit_window = load_vars(app);
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            if isa(app.A_cell{5},'sfit')
                app.current_cell = app.A_cell;
                app.current_cell_name = 'A';
                plotfit(app, 'Acceleration');
            else
                disp(app.handles,'No fit for A exists');
                disp(app.handles,'Import or simulate');
            end
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            if isa(app.B_cell{5},'sfit')
                app.current_cell = app.B_cell;
                app.current_cell_name = 'B';
                plotfit(app, 'Speed');
            else
                disp(app.handles,'No fit for B exists');
                disp(app.handles,'Import or simulate');
            end
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            if isa(app.G_cell{5},'sfit')
                app.current_cell = app.G_cell;
                app.current_cell_name = 'G';
                plotfit(app, 'Jerk');
            else
                disp(app.handles,'No fit for G exists');
                disp(app.handles,'Import or simulate');
            end
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            if isa(app.L_cell{5},'sfit')
                app.current_cell = app.L_cell;
                app.current_cell_name = 'L';
                plotfit(app, 'Acceleration');
            else
                disp(app.handles,'No fit for L exists');
                disp(app.handles,'Import or simulate');
            end
        end

        % Value changed function: Data, Fit, Span
        function FitValueChanged(app, event)
            if ~app.fit_plot_active  ; return; end
                
            plots_shown = [app.Fit.Value, app.Data.Value];
            axes(app.fit_plot_axes)
            [az,el] = view;
            
            if ~(app.Span.Value == app.current_cell{4}*100)                
                progress = uiprogressdlg(app.UIFigure,'Title','Refitting', 'Indeterminate','on');
                ft = fittype( 'loess' );
                opts = fitoptions( 'Method', 'LowessFit' );
                opts.Normalize = 'on';    
                opts.Span = app.Span.Value*1e-2;                
                current_fit = fit([app.current_cell{1}, app.current_cell{2}], app.current_cell{3}, ft, opts);
                app.current_cell{4} = opts.Span;
                app.current_cell{5} = current_fit;
                close(progress);
            end

            if isequal(plots_shown, [1 1])
                plot(app.current_cell{5}, [app.current_cell{1}, app.current_cell{2}], app.current_cell{3});
            elseif isequal(plots_shown,[0 1])                     
                scatter3(app.current_cell{1}, app.current_cell{2}, app.current_cell{3});
            elseif isequal(plots_shown,[1 0])
                plot(app.current_cell{5});
            else
                plot([[ 0],[0 ]], [0 0]);
            end 
            
            view([az,el])           
        end

        % Button pushed function: Save_fit
        function Save_fitButtonPushed(app, event)
            value = app.current_cell_name;
            switch value
                case 'A'
                    app.A_cell = app.current_cell;
                    disp(app.handles,'New fit for A saved to programmer')
                    disp(app.handles,'NB: profile is not saved!')
                case 'B'
                    app.B_cell = app.current_cell;
                    disp(app.handles,'New fit for A saved to programmer')
                    disp(app.handles,'NB: profile is not saved!')
                    
                case 'G'
                    app.G_cell = app.current_cell;
                    disp(app.handles,'New fit for A saved to programmer')
                    disp(app.handles,'NB: profile is not saved!')
                    
                case 'L'
                    app.L_cell = app.current_cell;
                    disp(app.handles,'New fit for A saved to programmer')
                    disp(app.handles,'NB: profile is not saved!')
                    
                case 'BR_X'
                    app.BR_X_cell = app.current_cell;
                    disp(app.handles,'New fit for A saved to programmer')
                    disp(app.handles,'NB: profile is not saved!')
                    
                case 'BR_T'
                    app.BR_T_cell = app.current_cell;
                    disp(app.handles,'New fit for A saved to programmer')
                    disp(app.handles,'NB: profile is not saved!')                   
            end
            close(app.fit_plot.fig);
        end

        % Button pushed function: Br_TButton
        function Br_TButtonPushed(app, event)
            if isa(app.BR_T_cell{5},'sfit')
                app.current_cell = app.BR_T_cell;
                app.current_cell_name = 'BR_T';
                plotfit(app, 'Time');
            else
                disp(app.handles,'No fit for BR_T exists');
                disp(app.handles,'Import or simulate');
            end
        end

        % Button pushed function: Br_XButton
        function Br_XButtonPushed(app, event)
            if isa(app.BR_X_cell{5},'sfit')
                app.current_cell = app.BR_X_cell;
                app.current_cell_name = 'BR_X';
                plotfit(app, 'Distance');
            else
                disp(app.handles,'No fit for BR_X exists');
                disp(app.handles,'Import or simulate');
            end
        end

        % Button pushed function: LoadFit_2
        function LoadFit_2ButtonPushed(app, event)
            answer = questdlg('This action will delete all programmer and profile data. Are you sure?','Confirm','Yes','Cancel','Cancel');
            if ~strcmp(answer,'Yes')
                return;
            end 
            delete_profile(app);
            clear_programmer(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ChangeGUI = uibutton(app.UIFigure, 'push');
            app.ChangeGUI.ButtonPushedFcn = createCallbackFcn(app, @ChangeGUIButtonPushed, true);
            app.ChangeGUI.Text = 'Change GUI';
            app.Console = uitextarea(app.UIFigure);
            app.Console.Editable = 'off';
            app.Console.Value = {'Started'};
            app.CarpropertiesPanel = uipanel(app.UIFigure);
            app.CarpropertiesPanel.Title = 'Car properties';
            app.F_maxEditFieldLabel = uilabel(app.CarpropertiesPanel);
            app.F_maxEditFieldLabel.Text = 'F_max';
            app.F_max = uieditfield(app.CarpropertiesPanel, 'numeric');
            app.massLabel = uilabel(app.CarpropertiesPanel);
            app.massLabel.Text = 'mass';
            app.mass = uieditfield(app.CarpropertiesPanel, 'numeric');
            app.ALabel = uilabel(app.CarpropertiesPanel);
            app.ALabel.Text = 'A';
            app.Acoef = uieditfield(app.CarpropertiesPanel, 'numeric');
            app.BLabel = uilabel(app.CarpropertiesPanel);
            app.BLabel.Text = 'B';
            app.Bcoef = uieditfield(app.CarpropertiesPanel, 'numeric');
            app.CLabel = uilabel(app.CarpropertiesPanel);
            app.CLabel.Text = 'C';
            app.Ccoef = uieditfield(app.CarpropertiesPanel, 'numeric');
            app.DeterminefrictioncoefficientsButton = uibutton(app.CarpropertiesPanel, 'push');
            app.DeterminefrictioncoefficientsButton.ButtonPushedFcn = createCallbackFcn(app, @DeterminefrictioncoefficientsButtonPushed, true);
            app.DeterminefrictioncoefficientsButton.Text = {'Determine friction'; 'coefficients'};
            app.ProfileDropDown = uidropdown(app.UIFigure);
            app.ProfileDropDown.Items = {'Profile 1', 'Profile 2', 'Profile 3', 'Profile 4', 'Profile 5', 'Auto save'};
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileDropDownValueChanged, true);
            app.ProfileDropDown.Value = 'Profile 1';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.FitsPanel = uipanel(app.UIFigure);
            app.FitsPanel.Title = 'Fits';
            app.AButton = uibutton(app.FitsPanel, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.FitsPanel, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.GButton = uibutton(app.FitsPanel, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.LButton = uibutton(app.FitsPanel, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.Br_TButton = uibutton(app.FitsPanel, 'push');
            app.Br_TButton.ButtonPushedFcn = createCallbackFcn(app, @Br_TButtonPushed, true);
            app.Br_TButton.Text = 'Br_T';
            app.Br_XButton = uibutton(app.FitsPanel, 'push');
            app.Br_XButton.ButtonPushedFcn = createCallbackFcn(app, @Br_XButtonPushed, true);
            app.Br_XButton.Text = 'Br_X';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TimingValuesPanel = uipanel(app.UIFigure);
            app.TimingValuesPanel.Title = 'Timing Values';
            app.CTPCEditFieldLabel = uilabel(app.TimingValuesPanel);
            app.CTPCEditFieldLabel.Text = 'CTPC';
            app.CTPC = uieditfield(app.TimingValuesPanel, 'numeric');
            app.PCTCEditFieldLabel = uilabel(app.TimingValuesPanel);
            app.PCTCEditFieldLabel.Text = 'PCTC';
            app.PCTC = uieditfield(app.TimingValuesPanel, 'numeric');
            app.EditField_2Label = uilabel(app.TimingValuesPanel);
            app.EditField_2Label.Text = '-';
            app.EditField_2 = uieditfield(app.TimingValuesPanel, 'numeric');
            app.EditField_3Label = uilabel(app.TimingValuesPanel);
            app.EditField_3Label.Text = '-';
            app.EditField_3 = uieditfield(app.TimingValuesPanel, 'numeric');
            app.fit_par = uipanel(app.UIFigure);
            app.fit_par.Title = 'A fit parameters';
            app.Save_fit = uibutton(app.fit_par, 'push');
            app.Save_fit.ButtonPushedFcn = createCallbackFcn(app, @Save_fitButtonPushed, true);
            app.Save_fit.Text = 'Save';
            app.SpanEditFieldLabel = uilabel(app.fit_par);
            app.SpanEditFieldLabel.Text = 'Span';
            app.Span = uieditfield(app.fit_par, 'numeric');
            app.Span.ValueChangedFcn = createCallbackFcn(app, @FitValueChanged, true);
            app.Fit = uicheckbox(app.fit_par);
            app.Fit.ValueChangedFcn = createCallbackFcn(app, @FitValueChanged, true);
            app.Fit.Text = 'Fit';
            app.Data = uicheckbox(app.fit_par);
            app.Data.ValueChangedFcn = createCallbackFcn(app, @FitValueChanged, true);
            app.Data.Text = 'Data';
            app.SimparametersPanel = uipanel(app.UIFigure);
            app.SimparametersPanel.Title = 'Sim parameters';
            app.VmaxEditFieldLabel = uilabel(app.SimparametersPanel);
            app.VmaxEditFieldLabel.Text = 'Vmax';
            app.Vmax = uieditfield(app.SimparametersPanel, 'numeric');
            app.VstepsEditFieldLabel = uilabel(app.SimparametersPanel);
            app.VstepsEditFieldLabel.Text = 'Vsteps';
            app.Vsteps = uieditfield(app.SimparametersPanel, 'numeric');
            app.FstepsEditFieldLabel = uilabel(app.SimparametersPanel);
            app.FstepsEditFieldLabel.Text = 'Fsteps';
            app.Fsteps = uieditfield(app.SimparametersPanel, 'numeric');
            app.EditField_4Label = uilabel(app.SimparametersPanel);
            app.EditField_4Label.Text = '-';
            app.EditField_4 = uieditfield(app.SimparametersPanel, 'numeric');
            app.LoadFit = uibutton(app.UIFigure, 'push');
            app.LoadFit.ButtonPushedFcn = createCallbackFcn(app, @LoadFitPushed, true);
            app.LoadFit.Text = 'Load Fit';
            app.LoadFit_2 = uibutton(app.UIFigure, 'push');
            app.LoadFit_2.ButtonPushedFcn = createCallbackFcn(app, @LoadFit_2ButtonPushed, true);
            app.LoadFit_2.Text = 'Delete';
        end
    end

    methods (Access = public)

        % Construct app
        function app = model_design(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end