classdef sim_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.NumericEditField
        getposition     matlab.ui.control.StateButton
    end


    properties (Access = private)
        map % Description
        map_axes
        map_active
    end

    methods (Access = private)
    
        function plotCloseRequest(app, ~)
            app.map.active = 0;
            delete(app.map.fig)
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.map.active = 0;
        end

        % Value changed function: getposition
        function getpositionValueChanged(app, event)
            if ~app.map.active
                app.map.fig = figure;
                app.map.active = 1;
                app.map.axes = subplot(1,1,1);
                app.map.fig.CloseRequestFcn = createCallbackFcn(app, @plotCloseRequest, true);
            end
                axes(app.map.axes)
                imagesc([0 1 0; 1 0 1; 0 1 0]);
                [x,y] = ginput(1);
                app.EditField.Value = x;
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.getposition = uibutton(app.UIFigure, 'state');
            app.getposition.ValueChangedFcn = createCallbackFcn(app, @getpositionValueChanged, true);
            app.getposition.Text = 'get position';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sim_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end