classdef template_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ChangeGUI              matlab.ui.control.Button
        ConsoleLabel           matlab.ui.control.Label
        Console                matlab.ui.control.TextArea
        ConnectBT              matlab.ui.control.StateButton
        ComportEditFieldLabel  matlab.ui.control.Label
        Comport                matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        handles %property storing handle to commen file. Do not delete
        mainfig
    end

    methods (Access = public)
    
        function disp(app,string)
            %content of this function may be deleted but not the function itself;
            app.Console.Value = [string;app.Console.Value];
        end
    
        function update(app,source)
            if app.handles.update_gui
                %spesific updates depending on function caller
                %more cases may need to be added later to stay compatible
                %with different classes
                switch source
                    case 'COMM'
                       
                    case 'KITT'
                        
                end
              
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            if nargin ~=2
                %app can not start without a parent
                delete(app)
            else
                app.handles = parent;
                parent.gui_available = 1;
                %Edit do let it point to main UIFIG
                %used to display loading bars etc;
                app.mainfig = app.UIFigure;
                %common comport connection acros all gui's
                if app.handles.comm.connected || app.handles.comm.vir_con
                    app.ConnectBT.Value = true;
                    app.Comport.Value = str2num(app.handles.comm.comport);
                    app.Comport.Enable = 'off';
                end
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.handles)            
        end

        % Button pushed function: ChangeGUI
        function ChangeGUIButtonPushed(app, event)
            app.handles.gui_available = 0;
            stardup_gui(app.handles);
            delete(app);
        end

        % Value changed function: ConnectBT
        function ConnectBTValueChanged(app, event)
            value = app.ConnectBT.Value;
            app.ConnectBT.Enable = 'off';
            app.Comport.Enable = 'off';
            %gui only updates during downtime
            pause(.01)
            switch value
                case 1
                    result = open(app.handles.comm,app.Comport.Value);
                    if ~result
                        app.ConnectBT.Value = 0;
                        app.Comport.Enable = 'on';
                    end
                case 0
                    close(app.handles.comm)
                    app.Comport.Enable = 'on';
            end
            app.ConnectBT.Enable = 'on'; 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ChangeGUI = uibutton(app.UIFigure, 'push');
            app.ChangeGUI.ButtonPushedFcn = createCallbackFcn(app, @ChangeGUIButtonPushed, true);
            app.ChangeGUI.Text = 'Change GUI';
            app.ConsoleLabel = uilabel(app.UIFigure);
            app.ConsoleLabel.Text = 'Console';
            app.Console = uitextarea(app.UIFigure);
            app.Console.Value = {'Started'};
            app.ConnectBT = uibutton(app.UIFigure, 'state');
            app.ConnectBT.ValueChangedFcn = createCallbackFcn(app, @ConnectBTValueChanged, true);
            app.ConnectBT.Text = 'Connect';
            app.ComportEditFieldLabel = uilabel(app.UIFigure);
            app.ComportEditFieldLabel.Text = 'Comport';
            app.Comport = uieditfield(app.UIFigure, 'numeric');
            app.Comport.RoundFractionalValues = 'on';
            app.Comport.Value = 4;
        end
    end

    methods (Access = public)

        % Construct app
        function app = template_gui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end