classdef test_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TestGuiUIFigure           matlab.ui.Figure
        ChangeGUI                 matlab.ui.control.Button
        ConsoleLabel              matlab.ui.control.Label
        Console                   matlab.ui.control.TextArea
        ComportEditFieldLabel     matlab.ui.control.Label
        Comport                   matlab.ui.control.NumericEditField
        ConnectBT                 matlab.ui.control.StateButton
        CommunicationPanel        matlab.ui.container.Panel
        TextArea                  matlab.ui.control.TextArea
        SendButton                matlab.ui.control.Button
        GetButton                 matlab.ui.control.Button
        DirectionEditFieldLabel   matlab.ui.control.Label
        direction                 matlab.ui.control.NumericEditField
        R_sensEditFieldLabel      matlab.ui.control.Label
        distanceR                 matlab.ui.control.NumericEditField
        VoltageEditFieldLabel     matlab.ui.control.Label
        voltage                   matlab.ui.control.NumericEditField
        f_bEditFieldLabel         matlab.ui.control.Label
        f_b                       matlab.ui.control.NumericEditField
        SpeedEditFieldLabel       matlab.ui.control.Label
        speed                     matlab.ui.control.NumericEditField
        L_sensEditFieldLabel      matlab.ui.control.Label
        distanceL                 matlab.ui.control.NumericEditField
        f_cEditFieldLabel         matlab.ui.control.Label
        f_c                       matlab.ui.control.NumericEditField
        repEditFieldLabel         matlab.ui.control.Label
        rep                       matlab.ui.control.NumericEditField
        codeEditFieldLabel        matlab.ui.control.Label
        code                      matlab.ui.control.EditField
        beacon                    matlab.ui.control.CheckBox
        loadButton                matlab.ui.control.Button
        WallbreakButton_2         matlab.ui.control.Button
        AbortButton               matlab.ui.control.StateButton
        RuntimeEditFieldLabel     matlab.ui.control.Label
        Runtime                   matlab.ui.control.NumericEditField
        StartspeedEditFieldLabel  matlab.ui.control.Label
        Startspeed                matlab.ui.control.NumericEditField
        BreakforceEditFieldLabel  matlab.ui.control.Label
        Breakforce                matlab.ui.control.NumericEditField
        DistanceEditFieldLabel    matlab.ui.control.Label
        Distance                  matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        handles % Description
        mainfig
    end

    methods (Access = public)
    
        function disp(app,string)
            app.Console.Value = [string;app.Console.Value];
        end
    
        function update(app,source)
            if app.handles.update_gui
                switch source
                    case 'COMM'
                            app.direction.Value = app.handles.comm.direction;
                            app.speed.Value = app.handles.comm.speed;
                            app.distanceL.Value = app.handles.comm.distanceL;
                            app.distanceR.Value = app.handles.comm.distanceR;
                            app.voltage.Value = app.handles.comm.voltage';
                            app.code.Value = app.handles.comm.code;
                            app.f_c.Value = app.handles.comm.f_c;
                            app.f_b.Value = app.handles.comm.f_b;
                            app.rep.Value = app.handles.comm.rep;
                            
                            switch app.handles.comm.beacon
                                case 'on'
                                    app.beacon.Value = 1;
                                case 'off'
                                   app.beacon.Value = 0; 
                            end

                    case 'KITT'
                end
              
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            if nargin ~=2
                delete(app)
            else
                app.handles = parent;
                parent.gui_available = 1;
                app.mainfig = app.TestGuiUIFigure;
                if app.handles.comm.connected || app.handles.comm.vir_con
                    app.ConnectBT.Value = true;
                    app.Comport.Value = str2num(app.handles.comm.comport);
                    app.Comport.Enable = 'off';
                end
            end 
        end

        % Close request function: TestGuiUIFigure
        function TestGuiUIFigureCloseRequest(app, event)
            delete(app.handles)
        end

        % Button pushed function: ChangeGUI
        function ChangeGUIButtonPushed(app, event)
            app.handles.gui_available = 0;            
            stardup_gui(app.handles);
            delete(app);
        end

        % Value changed function: ConnectBT
        function ConnectBTValueChanged(app, event)
            value = app.ConnectBT.Value;
            app.ConnectBT.Enable = 'off';
            app.Comport.Enable = 'off';
            pause(.01)
            switch value
                case 1
                    result = open(app.handles.comm,app.Comport.Value);
                    if ~result
                        app.ConnectBT.Value = 0;
                        app.Comport.Enable = 'on';
                    end
                case 0
                    close(app.handles.comm)
                    app.Comport.Enable = 'on';
            end
            app.ConnectBT.Enable = 'on';
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            send(app.handles.comm,app.TextArea.Value);
            assignin('base','TextArea',app.TextArea.Value);
            app.TextArea.Value = {''};
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            get_status(app.handles.comm, 'All')
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            uiopen('load')
        end

        % Button pushed function: WallbreakButton_2
        function WallbreakButton_2Pushed(app, event)
            app.Runtime.Value = wall_break(app.handles.kitt, app.Startspeed.Value, app.Breakforce.Value, app.Distance.Value/100);
        end

        % Value changed function: AbortButton
        function AbortButtonValueChanged(app, event)
            value = app.AbortButton.Value;
                if value
                    app.handles.abort = 1;     
                else
                    app.handles.abort = 0;
                end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TestGuiUIFigure = uifigure;
            app.TestGuiUIFigure.Name = 'Test Gui';
            app.TestGuiUIFigure.CloseRequestFcn = createCallbackFcn(app, @TestGuiUIFigureCloseRequest, true);
            app.ChangeGUI = uibutton(app.TestGuiUIFigure, 'push');
            app.ChangeGUI.ButtonPushedFcn = createCallbackFcn(app, @ChangeGUIButtonPushed, true);
            app.ChangeGUI.Text = 'Change GUI';
            app.ConsoleLabel = uilabel(app.TestGuiUIFigure);
            app.ConsoleLabel.Text = 'Console';
            app.Console = uitextarea(app.TestGuiUIFigure);
            app.Console.Editable = 'off';
            app.Console.Value = {'Started'};
            app.ComportEditFieldLabel = uilabel(app.TestGuiUIFigure);
            app.ComportEditFieldLabel.Text = 'Comport';
            app.Comport = uieditfield(app.TestGuiUIFigure, 'numeric');
            app.Comport.RoundFractionalValues = 'on';
            app.Comport.Value = 4;
            app.ConnectBT = uibutton(app.TestGuiUIFigure, 'state');
            app.ConnectBT.ValueChangedFcn = createCallbackFcn(app, @ConnectBTValueChanged, true);
            app.ConnectBT.Text = 'Connect';
            app.CommunicationPanel = uipanel(app.TestGuiUIFigure);
            app.CommunicationPanel.Title = 'Communication';
            app.TextArea = uitextarea(app.CommunicationPanel);
            app.SendButton = uibutton(app.CommunicationPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.GetButton = uibutton(app.CommunicationPanel, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.DirectionEditFieldLabel = uilabel(app.CommunicationPanel);
            app.DirectionEditFieldLabel.Text = 'Direction';
            app.direction = uieditfield(app.CommunicationPanel, 'numeric');
            app.direction.Editable = 'off';
            app.R_sensEditFieldLabel = uilabel(app.CommunicationPanel);
            app.R_sensEditFieldLabel.Text = 'R_sens';
            app.distanceR = uieditfield(app.CommunicationPanel, 'numeric');
            app.distanceR.Editable = 'off';
            app.VoltageEditFieldLabel = uilabel(app.CommunicationPanel);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.voltage = uieditfield(app.CommunicationPanel, 'numeric');
            app.voltage.Editable = 'off';
            app.f_bEditFieldLabel = uilabel(app.CommunicationPanel);
            app.f_bEditFieldLabel.Text = 'f_b';
            app.f_b = uieditfield(app.CommunicationPanel, 'numeric');
            app.f_b.Editable = 'off';
            app.SpeedEditFieldLabel = uilabel(app.CommunicationPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.speed = uieditfield(app.CommunicationPanel, 'numeric');
            app.speed.Editable = 'off';
            app.L_sensEditFieldLabel = uilabel(app.CommunicationPanel);
            app.L_sensEditFieldLabel.Text = 'L_sens';
            app.distanceL = uieditfield(app.CommunicationPanel, 'numeric');
            app.distanceL.Editable = 'off';
            app.f_cEditFieldLabel = uilabel(app.CommunicationPanel);
            app.f_cEditFieldLabel.Text = 'f_c';
            app.f_c = uieditfield(app.CommunicationPanel, 'numeric');
            app.f_c.Editable = 'off';
            app.repEditFieldLabel = uilabel(app.CommunicationPanel);
            app.repEditFieldLabel.Text = 'rep';
            app.rep = uieditfield(app.CommunicationPanel, 'numeric');
            app.rep.Editable = 'off';
            app.codeEditFieldLabel = uilabel(app.CommunicationPanel);
            app.codeEditFieldLabel.Text = 'code';
            app.code = uieditfield(app.CommunicationPanel, 'text');
            app.code.Editable = 'off';
            app.beacon = uicheckbox(app.CommunicationPanel);
            app.beacon.Text = 'Beacon';
            app.loadButton = uibutton(app.TestGuiUIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.WallbreakButton_2 = uibutton(app.TestGuiUIFigure, 'push');
            app.WallbreakButton_2.ButtonPushedFcn = createCallbackFcn(app, @WallbreakButton_2Pushed, true);
            app.WallbreakButton_2.Text = 'Wall break';
            app.AbortButton = uibutton(app.TestGuiUIFigure, 'state');
            app.AbortButton.ValueChangedFcn = createCallbackFcn(app, @AbortButtonValueChanged, true);
            app.AbortButton.Text = 'Abort';
            app.RuntimeEditFieldLabel = uilabel(app.TestGuiUIFigure);
            app.RuntimeEditFieldLabel.Text = 'Runtime';
            app.Runtime = uieditfield(app.TestGuiUIFigure, 'numeric');
            app.StartspeedEditFieldLabel = uilabel(app.TestGuiUIFigure);
            app.StartspeedEditFieldLabel.Text = 'Start speed';
            app.Startspeed = uieditfield(app.TestGuiUIFigure, 'numeric');
            app.Startspeed.Limits = [155 165];
            app.Startspeed.Value = 160;
            app.BreakforceEditFieldLabel = uilabel(app.TestGuiUIFigure);
            app.BreakforceEditFieldLabel.Text = 'Break force';
            app.Breakforce = uieditfield(app.TestGuiUIFigure, 'numeric');
            app.Breakforce.Limits = [135 145];
            app.Breakforce.Value = 135;
            app.DistanceEditFieldLabel = uilabel(app.TestGuiUIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.Distance = uieditfield(app.TestGuiUIFigure, 'numeric');
            app.Distance.Limits = [10 200];
            app.Distance.ValueDisplayFormat = '%11.4g cm';
            app.Distance.Value = 30;
        end
    end

    methods (Access = public)

        % Construct app
        function app = test_gui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TestGuiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TestGuiUIFigure)
        end
    end
end