classdef stand_alone_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Console                      matlab.ui.control.TextArea
        ConnectBT                    matlab.ui.control.StateButton
        ComportEditFieldLabel        matlab.ui.control.Label
        Comport                      matlab.ui.control.NumericEditField
        TabGroup                     matlab.ui.container.TabGroup
        FieldSetupTab                matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        MakeFieldButton              matlab.ui.control.Button
        FieldNrSpinnerLabel          matlab.ui.control.Label
        FieldNrSpinner               matlab.ui.control.Spinner
        EditFieldLabel               matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        RatioEditFieldLabel          matlab.ui.control.Label
        RatioEditField               matlab.ui.control.NumericEditField
        SaveButton                   matlab.ui.control.Button
        DropDown                     matlab.ui.control.DropDown
        EditField2                   matlab.ui.control.NumericEditField
        EditField2_2                 matlab.ui.control.NumericEditField
        AddPositionButton            matlab.ui.control.Button
        DrivingTab                   matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        DriveButton                  matlab.ui.control.Button
        StraightspeedEditFieldLabel  matlab.ui.control.Label
        Straightspeed                matlab.ui.control.NumericEditField
        TurnspeedEditFieldLabel      matlab.ui.control.Label
        Turnspeed                    matlab.ui.control.NumericEditField
        DrivedurationEditFieldLabel  matlab.ui.control.Label
        Driveduration                matlab.ui.control.NumericEditField
        BrakespeedEditFieldLabel     matlab.ui.control.Label
        Brakespeed                   matlab.ui.control.NumericEditField
        DestinationsEditFieldLabel   matlab.ui.control.Label
        Destinations                 matlab.ui.control.EditField
        Collisionprevention          matlab.ui.control.CheckBox
        FieldNrSpinner_2Label        matlab.ui.control.Label
        FieldNrSpinner_2             matlab.ui.control.Spinner
        TestingTab                   matlab.ui.container.Tab
        DebugCheckBox                matlab.ui.control.CheckBox
        Cancel                       matlab.ui.control.StateButton
    end


    properties (Access = public)
        handles %property storing handle to commen file. Do not delete
        mainfig
        
        toggle = true;
        
        axes_forced = 0
    end

    methods (Access = public)
    
        function disp(app,string)
            %content of this function may be deleted but not the function itself;
            app.Console.Value = [string;app.Console.Value];
        end
    
        function update(app,source)
            if app.handles.update_gui
                %spesific updates depending on function caller
                %more cases may need to be added later to stay compatible
                %with different classes
                switch source
                    case 'COMM'
                       
                    case 'KITT'
                        
                end
              
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            path = mfilename('fullpath');
            [filepath,~,~] = fileparts(path);
            addpath(genpath(filepath));
            app.handles = handles(app.DebugCheckBox.Value, 1, 0, filepath);
            app.handles.gui = app;
            app.handles.gui_available = 1;            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.axes_forced == 1
                app.handles.field.free_field.active = 0;
            end
            delete(app.handles)
        end

        % Value changed function: ConnectBT
        function ConnectBTValueChanged(app, event)
            value = app.ConnectBT.Value;
            app.ConnectBT.Enable = 'off';
            app.Comport.Enable = 'off';
            %gui only updates during downtime
            pause(.01)
            switch value
                case 1
                    result = open(app.handles.comm,app.Comport.Value);
                    if ~result
                        app.ConnectBT.Value = 0;
                        app.Comport.Enable = 'on';
                    end
                case 0
                    close(app.handles.comm)
                    app.Comport.Enable = 'on';
            end
            app.ConnectBT.Enable = 'on'; 
        end

        % Value changed function: DebugCheckBox
        function DebugCheckBoxValueChanged(app, event)
            value = app.DebugCheckBox.Value;
            app.handles.Debug = value;
        end

        % Button pushed function: DriveButton
        function DriveButtonPushed(app, event)
           app.handles.field.free_field.axes = app.UIAxes2;
           app.handles.field.free_field.active = 1;
           app.axes_forced = 1;
            
            %change later
            order = [];
            dest = strsplit(app.Destinations.Value);
            for i = 1:length(dest)
                added = dest{i}
                switch added
                    case 'A'
                        order = [order;1];
                    case 'B'
                        order = [order;2];
                    case 'C'
                        order = [order;3];
                    case 'D'
                        order = [order;4];
                    otherwise
                        disp(app.handles, 'Location not recognized');
                        disp(app.handles, 'Add uppercase A/B/C/D');
                end
            end
            if length(order)<1
               disp(app.handles,'no destinations'); 
            end

            
            load(app.handles.field,app.FieldNrSpinner_2.Value);
            %
            
            runtime = drive_final(app.handles.kitt, order, app.Collisionprevention.Value, app.Straightspeed.Value, app.Turnspeed.Value, app.Driveduration.Value, app.Brakespeed.Value);
        end

        % Value changed function: Cancel
        function CancelValueChanged(app, event)
            value = app.Cancel.Value;
            send(app.handles.comm, {'A0'})
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Console = uitextarea(app.UIFigure);
            app.Console.Value = {'Started'};
            app.ConnectBT = uibutton(app.UIFigure, 'state');
            app.ConnectBT.ValueChangedFcn = createCallbackFcn(app, @ConnectBTValueChanged, true);
            app.ConnectBT.Text = 'Connect';
            app.ComportEditFieldLabel = uilabel(app.UIFigure);
            app.ComportEditFieldLabel.Text = 'Comport';
            app.Comport = uieditfield(app.UIFigure, 'numeric');
            app.Comport.RoundFractionalValues = 'on';
            app.Comport.Value = 4;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FieldSetupTab = uitab(app.TabGroup);
            app.FieldSetupTab.Title = 'Field Setup';
            app.UIAxes = uiaxes(app.FieldSetupTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MakeFieldButton = uibutton(app.FieldSetupTab, 'push');
            app.MakeFieldButton.Text = 'Make Field';
            app.FieldNrSpinnerLabel = uilabel(app.FieldSetupTab);
            app.FieldNrSpinnerLabel.Text = 'Field Nr';
            app.FieldNrSpinner = uispinner(app.FieldSetupTab);
            app.EditFieldLabel = uilabel(app.FieldSetupTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.FieldSetupTab, 'text');
            app.RatioEditFieldLabel = uilabel(app.FieldSetupTab);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.FieldSetupTab, 'numeric');
            app.SaveButton = uibutton(app.FieldSetupTab, 'push');
            app.SaveButton.Text = 'Save';
            app.DropDown = uidropdown(app.FieldSetupTab);
            app.EditField2 = uieditfield(app.FieldSetupTab, 'numeric');
            app.EditField2_2 = uieditfield(app.FieldSetupTab, 'numeric');
            app.AddPositionButton = uibutton(app.FieldSetupTab, 'push');
            app.AddPositionButton.Text = 'Add Position';
            app.DrivingTab = uitab(app.TabGroup);
            app.DrivingTab.Title = 'Driving';
            app.UIAxes2 = uiaxes(app.DrivingTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.DriveButton = uibutton(app.DrivingTab, 'push');
            app.DriveButton.ButtonPushedFcn = createCallbackFcn(app, @DriveButtonPushed, true);
            app.DriveButton.Text = 'Drive';
            app.StraightspeedEditFieldLabel = uilabel(app.DrivingTab);
            app.StraightspeedEditFieldLabel.Text = 'Straight speed';
            app.Straightspeed = uieditfield(app.DrivingTab, 'numeric');
            app.Straightspeed.Limits = [155 165];
            app.Straightspeed.Value = 160;
            app.TurnspeedEditFieldLabel = uilabel(app.DrivingTab);
            app.TurnspeedEditFieldLabel.Text = 'Turn speed';
            app.Turnspeed = uieditfield(app.DrivingTab, 'numeric');
            app.Turnspeed.Limits = [155 165];
            app.Turnspeed.Value = 160;
            app.DrivedurationEditFieldLabel = uilabel(app.DrivingTab);
            app.DrivedurationEditFieldLabel.Text = 'Drive duration';
            app.Driveduration = uieditfield(app.DrivingTab, 'numeric');
            app.Driveduration.Limits = [0.3 4];
            app.Driveduration.Value = 1;
            app.BrakespeedEditFieldLabel = uilabel(app.DrivingTab);
            app.BrakespeedEditFieldLabel.Text = 'Brake speed';
            app.Brakespeed = uieditfield(app.DrivingTab, 'numeric');
            app.Brakespeed.Limits = [135 145];
            app.Brakespeed.Value = 140;
            app.DestinationsEditFieldLabel = uilabel(app.DrivingTab);
            app.DestinationsEditFieldLabel.Text = 'Destinations';
            app.Destinations = uieditfield(app.DrivingTab, 'text');
            app.Collisionprevention = uicheckbox(app.DrivingTab);
            app.Collisionprevention.Text = 'Collision prevention';
            app.FieldNrSpinner_2Label = uilabel(app.DrivingTab);
            app.FieldNrSpinner_2Label.Text = 'Field Nr';
            app.FieldNrSpinner_2 = uispinner(app.DrivingTab);
            app.TestingTab = uitab(app.TabGroup);
            app.TestingTab.Title = 'Testing';
            app.DebugCheckBox = uicheckbox(app.UIFigure);
            app.DebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugCheckBoxValueChanged, true);
            app.DebugCheckBox.Text = 'Debug';
            app.DebugCheckBox.Value = true;
            app.Cancel = uibutton(app.UIFigure, 'state');
            app.Cancel.ValueChangedFcn = createCallbackFcn(app, @CancelValueChanged, true);
            app.Cancel.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = stand_alone_gui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end