classdef stardup_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFig       matlab.ui.Figure
        DropDown      matlab.ui.control.DropDown
        StartButton   matlab.ui.control.Button
        OptionsPanel  matlab.ui.container.Panel
        Parpool       matlab.ui.control.CheckBox
        UpdateGUI     matlab.ui.control.CheckBox
        Debug         matlab.ui.control.CheckBox
        data_acq      matlab.ui.control.CheckBox
    end


    properties (Access = private)
        mode = 1; % Description
        handles
        close_mode = 0;
        %DO NOT CHANGE THE ORDER OF THESE PATHS
        folders = [{'\data_files\profiles'}; %models(1)
                    {'\data_files\fields'} %fields with start/stop points (2)
                    {'\data_files\runs'} %data from runs with data acquisition enabled (3)
                    {'\data_files\close_saves'}] %close save variables(4)
    end

    methods (Access = private)
    
        function add_folders(app,filepath)
            for k = 1:length(app.folders)
               if ~exist(strcat(filepath,app.folders{k}),'dir')
                  mkdir(strcat(filepath,app.folders{k})); 
               end
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, handle)
            clc;
             if nargin ~= 1
                 app.mode = 0;
                 app.handles = handle;
                 app.Debug.Enable = 'off';
                 app.UpdateGUI.Enable = 'off';
                 app.data_acq.Enable = 'off';
                 app.Parpool.Enable = 'off';
             end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.close_mode = 1;
            if app.mode
               %get path of main file, create folders if needed and add all to matlab search path;
               if app.Parpool.Value
                    try parpool; catch; end
               end
               path = mfilename('fullpath');
               [filepath,~,~] = fileparts(path);
               add_folders(app,filepath);
               addpath(genpath(filepath));
               app.handles =  handles(app.Debug.Value, app.UpdateGUI.Value, app.data_acq.Value, filepath);
            end
            selected = app.DropDown.Value;
            switch selected
                case 'Challenges'
                    app.handles.gui = Challenges(app.handles);
                    
                case 'Template'
                    app.handles.gui = template_gui(app.handles);
                    
                case 'Gui tests'
                    app.handles.gui = test_gui(app.handles);
                    
                case'Model Design'
                    app.handles.gui = model_design(app.handles);
                    
                case 'Field'
                    app.handles.gui = field_gui(app.handles);
%                     
%                 case 'Mario Kart'
                    
            end
                    app.handles.gui_available = 1;
                    close(app.MainFig);          
        end

        % Close request function: MainFig
        function MainFigCloseRequest(app, event)
            if ~app.mode && ~app.close_mode
                delete(app.handles) 
            end
            delete(app)  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFig = uifigure;
            app.MainFig.Name = 'Kitt Stardup';
            app.MainFig.CloseRequestFcn = createCallbackFcn(app, @MainFigCloseRequest, true);
            app.DropDown = uidropdown(app.MainFig);
            app.DropDown.Items = {'Challenges', 'Template', 'Gui tests', 'Field', 'Model', 'Mario Kart', 'Model Design'};
            app.DropDown.Value = 'Challenges';
            app.StartButton = uibutton(app.MainFig, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OptionsPanel = uipanel(app.MainFig);
            app.OptionsPanel.Title = 'Options';
            app.Parpool = uicheckbox(app.OptionsPanel);
            app.Parpool.Text = 'Parpool';
            app.UpdateGUI = uicheckbox(app.OptionsPanel);
            app.UpdateGUI.Text = 'Update Gui';
            app.UpdateGUI.Value = true;
            app.Debug = uicheckbox(app.OptionsPanel);
            app.Debug.Text = 'Debug Enable';
            app.data_acq = uicheckbox(app.OptionsPanel);
            app.data_acq.Text = 'Data acquisition';
        end
    end

    methods (Access = public)

        % Construct app
        function app = stardup_gui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFig)
        end
    end
end